classdef tableau_de_bord < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp                           matlab.ui.control.Lamp
        DemarreurvoitureSwitch_2       matlab.ui.control.ToggleSwitch
        DemarreurvoitureSwitch_2Label  matlab.ui.control.Label
        RadiateurLamp                  matlab.ui.control.Lamp
        RadiateurLampLabel             matlab.ui.control.Label
        CarburantLamp                  matlab.ui.control.Lamp
        CarburantLampLabel             matlab.ui.control.Label
        MoteurLamp                     matlab.ui.control.Lamp
        MoteurLampLabel                matlab.ui.control.Label
        stopLamp                       matlab.ui.control.Lamp
        stopLampLabel                  matlab.ui.control.Label
        TempatureCGauge                matlab.ui.control.NinetyDegreeGauge
        TempatureCGaugeLabel           matlab.ui.control.Label
        NiveaudeCarburantGauge         matlab.ui.control.NinetyDegreeGauge
        NiveaudeCarburantGaugeLabel    matlab.ui.control.Label
        ComptetoursRPMGauge            matlab.ui.control.Gauge
        ComptetoursRPMGaugeLabel       matlab.ui.control.Label
        VitesseKmhGauge                matlab.ui.control.Gauge
        VitesseKmhGaugeLabel           matlab.ui.control.Label
    end

    properties (Access = private)
        color_gris = [0.5 0.5 0.5]; % Definition de la couleur gris
        recpt_channel;
        carb = 48; %% Variable pour le stockage du niveau de carburant
        tmp; %% Variable de température
        rpm;%% Variable du compte rpm

    end    
    methods (Access = private)
        %% Initiation du cannal de reception : Création et lancement
        function init_CANBUS(app)
            % Création du canal de réception
            app.recpt_channel = canChannel("MathWorks", "Virtual 1", 2);
            app.recpt_channel.Database = canDatabase("tableau_de_bord.dbc");
            % Définir le callback AVANT de démarrer le canal
            app.recpt_channel.MessageReceivedFcnCount = 1; % Déclenche après chaque message
            app.recpt_channel.MessageReceivedFcn = @(~,~)app.affecte_value();
            % Démarrer le canal
            start(app.recpt_channel);
            % Vérification de l'état
            if app.recpt_channel.Running
                disp("Le canal de réception est activé");
            else
                disp("Canal de réception est mal activé.");
            end
        end
        %% Initialisation des lampes d'indication
        function starting_Stoping(app)
            app.RadiateurLamp.Color = app.color_gris;
            app.CarburantLamp.Color = app.color_gris;
            app.MoteurLamp.Color = app.color_gris;
            app.stopLamp.Color = app.color_gris;
            app.Lamp.Color = app.color_gris;
        end
        %% Fonction de controle des led 
        function controle_led(app)
            %% Voyant moteur par contrôle du RPM & Temp
            if (125 <= app.tmp && app.tmp <= 150) || (6000 <= app.rpm && app.rpm <= 7000)
                app.MoteurLamp.Color = "red";
                app.stopLamp.Color = "red";
            elseif (105 <= app.tmp && app.tmp < 125) || (5500 <= app.rpm && app.rpm < 6000)
                app.MoteurLamp.Color = "yellow";
                app.stopLamp.Color = app.color_gris;
            else
                app.MoteurLamp.Color = app.color_gris;
                app.stopLamp.Color = app.color_gris;
            end
            %% Voyant du niveau de Carburant(OK)
            if 0 <= app.carb && app.carb < 12.5
                app.CarburantLamp.Color = "red";
            elseif 12.5 <= app.carb && app.carb < 25
                app.CarburantLamp.Color = "yellow";
            else
                app.CarburantLamp.Color = app.color_gris;
            end
            %% Voyant Radiateur (OK)
            if app.tmp > 70
                app.RadiateurLamp.Color = "yellow";
            else
                app.RadiateurLamp.Color = app.color_gris;
            end
        end
        %% Fonction pour récupérer les messages du cannal pour les affecter aux composants ( gauge) correcte
        function affecte_value(app)
            msg = app.recpt_channel.receive(Inf,"OutputFormat","timetable");
            % Affectation des valeur des données moteur et véhicle aux
            % composants correspondants
            for index = 1:height(msg)
                if msg.Name{index} == "MoteurData"
                    app.tmp = msg.Signals{index}.MoteurTemp;
                    if app.tmp == 0
                        app.tmp =70;
                    end
                    app.TempatureCGauge.Value = app.tmp;
                    app.rpm = msg.Signals{index}.MoteurRPM;
                    app.ComptetoursRPMGauge.Value = app.rpm;
                    disp(app.rpm);
                else
                    app.carb = msg.Signals{index}.NiveauCarburant;
                    if app.carb == 0
                        app.carb = 48;
                    elseif app.carb > 100%% Passage de 1 à 254 bloquer à 0
                        app.carb = 0;
                    end
                    app.NiveaudeCarburantGauge.Value = app.carb;
                    app.VitesseKmhGauge.Value = msg.Signals{index}.VitesseVehicule;
                end
                app.controle_led();%% Controle des lampes suivant la valeur des gauges.
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DemarreurvoitureSwitch_2
        function DemarreurvoitureSwitch_2ValueChanged(app, event)
            pw_btn = app.DemarreurvoitureSwitch_2.Value;
            if pw_btn == "Start"
                %% Condition initiale
                app.starting_Stoping();
                app.Lamp.Color = "green";
                app.init_CANBUS();%% Activation du cannal de reception
                % generateMsgsDb();%% Génération des messages sur le cannel de transmission pour la récupération
                app.affecte_value();
            else
                app.VitesseKmhGauge.Value = 0;
                app.ComptetoursRPMGauge.Value = 0;
                app.NiveaudeCarburantGauge.Value = 0;
                app.TempatureCGauge.Value = 0;
                app.starting_Stoping();
                stop(app.recpt_channel);%% Désactivation du canal de récption
                disp("Le canal de réception est désactivé.");
                app.Lamp.Color = app.color_gris;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VitesseKmhGaugeLabel = uilabel(app.UIFigure);
            app.VitesseKmhGaugeLabel.Text = 'Vitesse (Km/h)';
            app.VitesseKmhGauge = uigauge(app.UIFigure, 'circular');
            app.VitesseKmhGauge.Limits = [0 240];
            app.VitesseKmhGauge.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200 220 240];
            app.ComptetoursRPMGaugeLabel = uilabel(app.UIFigure);
            app.ComptetoursRPMGaugeLabel.Text = 'Compte-tours (RPM)';
            app.ComptetoursRPMGauge = uigauge(app.UIFigure, 'circular');
            app.ComptetoursRPMGauge.Limits = [0 7000];
            app.ComptetoursRPMGauge.MajorTicks = [0 1000 2000 3000 4000 5000 6000 7000];
            app.ComptetoursRPMGauge.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100 102 104 106 108 110 112 114 116 118 120 122 124 126 128 130 132 134 136 138 140 142 144 146 148 150 152 154];
            app.NiveaudeCarburantGaugeLabel = uilabel(app.UIFigure);
            app.NiveaudeCarburantGaugeLabel.Text = 'Niveau de Carburant';
            app.NiveaudeCarburantGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.NiveaudeCarburantGauge.MajorTicks = [0 10 25 50 75 100 125 150];
            app.NiveaudeCarburantGauge.MinorTicks = [0 10 35 65 90];
            app.TempatureCGaugeLabel = uilabel(app.UIFigure);
            app.TempatureCGaugeLabel.Text = 'Tempature (°C)';
            app.TempatureCGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.TempatureCGauge.Limits = [-10 150];
            app.TempatureCGauge.MajorTicks = [-10 0 10 50 100 150];
            app.TempatureCGauge.MinorTicks = [0 25 75 125];
            app.stopLampLabel = uilabel(app.UIFigure);
            app.stopLampLabel.Text = 'stop';
            app.stopLamp = uilamp(app.UIFigure);
            app.MoteurLampLabel = uilabel(app.UIFigure);
            app.MoteurLampLabel.Text = 'Moteur';
            app.MoteurLamp = uilamp(app.UIFigure);
            app.CarburantLampLabel = uilabel(app.UIFigure);
            app.CarburantLampLabel.Text = 'Carburant';
            app.CarburantLamp = uilamp(app.UIFigure);
            app.RadiateurLampLabel = uilabel(app.UIFigure);
            app.RadiateurLampLabel.Text = 'Radiateur';
            app.RadiateurLamp = uilamp(app.UIFigure);
            app.DemarreurvoitureSwitch_2Label = uilabel(app.UIFigure);
            app.DemarreurvoitureSwitch_2Label.Text = 'Demarreur voiture ';
            app.DemarreurvoitureSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.DemarreurvoitureSwitch_2.Items = {'Stop', 'Start'};
            app.DemarreurvoitureSwitch_2.ValueChangedFcn = createCallbackFcn(app, @DemarreurvoitureSwitch_2ValueChanged, true);
            app.DemarreurvoitureSwitch_2.Value = 'Stop';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tableau_de_bord

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end